classdef DPWiFi_TRX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        RunCount                        matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        VisualizedDPWiFiStreamDropDown  matlab.ui.control.DropDown
        MonitoredDPWiFiStreamLabel      matlab.ui.control.Label
        DPWiFiDataRateGbpsEditField     matlab.ui.control.NumericEditField
        DPWiFiDataRateGbpsLabel         matlab.ui.control.Label
        BandwidthMHzDropDown            matlab.ui.control.DropDown
        BandwidthMHzLabel               matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        outofLabel                      matlab.ui.control.Label
        EEditField                      matlab.ui.control.NumericEditField
        EEditFieldLabel                 matlab.ui.control.Label
        MIMOOrderDropDown               matlab.ui.control.DropDown
        DPMIMOOrderLabel                matlab.ui.control.Label
        WiFiMCSDropDown                 matlab.ui.control.DropDown
        ModulationCodingLabel           matlab.ui.control.Label
        ChannelPolarizationEditField    matlab.ui.control.NumericEditField
        ChannelDepolarizationLabel      matlab.ui.control.Label
        RxXPolIsolationdBcEditField     matlab.ui.control.NumericEditField
        RxXPolIsolationdBcEditFieldLabel  matlab.ui.control.Label
        ChPolarizationIndexEditField    matlab.ui.control.NumericEditField
        ChPolarizationIndexEditFieldLabel  matlab.ui.control.Label
        RxDepolarizationIndexEditField  matlab.ui.control.NumericEditField
        RxDPIndexLabel                  matlab.ui.control.Label
        BEREditField                    matlab.ui.control.NumericEditField
        BEREditFieldLabel               matlab.ui.control.Label
        DPWiFiTRXv4ModelLabel           matlab.ui.control.Label
        RxAdaptationLabel               matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        RUNButton                       matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    methods (Access = private)

        function Run_UIFigureFront(app)

            figure(app.UIFigure);

            drawnow;

        end

        function DisplayBER(app, modelName)
            
            splitName= strsplit(modelName, '/');

            % Extract the second part of the split string, which contains 'MCS13WiFi_DP32_NEW'
            modelName = splitName{2};
            simOut = sim('Simulink/B');
            B = simOut.B;

            simOut1 = sim('Simulink/E');
            E = simOut1.E;

            simOut2 = sim('Simulink/R');
            R = simOut2.R;
            
            s = simulation(modelName);
            s = setModelParameter(s, 'SaveState', 'on');

            if(app.CheckBox.Value == 1)
               
                app.EditField.Value = "Adapting Rx";
                pause(60);
                RxDpIndex = evalin('base', 'rxDpIndex');
                app.RxDepolarizationIndexEditField.Value = RxDpIndex;

            end

            app.EditField.FontSize = 28;
            app.EditField.Value = "Processing Symbol 1";

            initialize(s);

            if (app.RunCount ~= 1) 
                pause(0.1);
            else    
                pause(6);
            end    
            
            % Open necessary scopes and trigger updates
            scopesToOpen = {"MCS13WiFi_DP32/[S]", ... 
                            "MCS13WiFi_DP32/[S*]", ...
                            "MCS13WiFi_DP32/[S']", ...
                            "MCS13WiFi_DP32/[SS]", ...
                            "MCS13WiFi_DP32/[S*S]", ...
                            "MCS13WiFi_DP32/[S'S]", ...
                            "MCS13WiFi_DP32/[SC]", ...
                            "MCS13WiFi_DP32/[S*C]", ...
                            "MCS13WiFi_DP32/[S'C]"};

            % close the scopes
            for i = 1:length(scopesToOpen)

                if ~isdeployed && isempty(coder.target)
                    close_system(scopesToOpen{i});
                end

                pause(0.1);

            end

            pause(1);

            if ~isdeployed && isempty(coder.target)
                close_system("MCS13WiFi_DP32/[S*S]");
                close_system("MCS13WiFi_DP32/[SS]");
                close_system("MCS13WiFi_DP32/[S'S]");
                close_system("MCS13WiFi_DP32/[SC]");
                close_system("MCS13WiFi_DP32/[S'C]");
                close_system("MCS13WiFi_DP32/[S*C]");
            end

            start(s);
                
                app.EditField.FontSize = 28;
                app.EditField.Value = "Processing Symbol 2";
                app.BEREditField.Value = B(1);
                app.EEditField.Value = B(2);
                app.BEditField.Value = B(3);
                
            pause(s);

                % Open necessary scopes and trigger updates
                scopesToOpen = {"MCS13WiFi_DP32/[S]", ... 
                                "MCS13WiFi_DP32/[S*]", ...
                                "MCS13WiFi_DP32/[S']", ...
                                "MCS13WiFi_DP32/[SS]", ...
                                "MCS13WiFi_DP32/[S*S]", ...
                                "MCS13WiFi_DP32/[S'S]", ...
                                "MCS13WiFi_DP32/[SC]", ...
                                "MCS13WiFi_DP32/[S*C]", ...
                                "MCS13WiFi_DP32/[S'C]"};
    
                % Reopen the scopes
                for i = 1:length(scopesToOpen)

                    if ~isdeployed && isempty(coder.target)
                        open_system(scopesToOpen{i});
                    end

                end

                figure(app.UIFigure);

                app.EditField.FontSize = 28;
                app.EditField.Value = "Processing Symbol 3";

                %Extract the outputErr values
                %outputErr = s.SimulationOutput.outputErr;
            
                %Display or process outputErr 1-BER, 2-number of Error bit, 3-total bits
                %assignin('base', "outputErr", outputErr);
                app.BEREditField.Value = (B(2) + E(2))/(E(3)*2);
                app.EEditField.Value = B(2) + E(2);
                app.BEditField.Value = E(3)*2;

            step(s, 'PauseTime', 1.361e-05);
                
                %Extract the outputErr values
                %outputErr = s.SimulationOutput.outputErr;
            
                %Display or process outputErr 1-BER, 2-number of Error bit, 3-total bits
                %assignin('base', "outputErr", outputErr);
                figure(app.UIFigure);

            step(s, 'PauseTime', 2.722e-05);
                %Extract the outputErr values
                %outputErr = s.SimulationOutput.outputErr;
            
                %Display or process outputErr 1-BER, 2-number of Error bit, 3-total bits
                %assignin('base', "outputErr", outputErr);
                app.BEREditField.Value = (B(2) + E(2) + R(2))/(R(3)*3);
                app.EEditField.Value = B(2) + E(2) + R(2);
                app.BEditField.Value = R(3)*3;
                app.EditField.Value = "End/Ready";

            if(s.Status == "inactive")
                figure(app.UIFigure);
            end
                app.MIMOOrderDropDown.Enable = "on";
                app.BandwidthMHzDropDown.Enable = "on";
                app.WiFiMCSDropDown.Enable = "on";
                app.ChannelPolarizationEditField.Enable = "on";
                app.RxXPolIsolationdBcEditField.Enable = "on";
                app.CheckBox.Enable = "on";
                app.VisualizedDPWiFiStreamDropDown.Enable = "on";
                app.RUNButton.Enable = "on";

        end
        
        function RunModel(app, modelName, testModelName, isChecked, n)

            % Split the model name into two parts using the delimiter '/'
            splitName= strsplit(modelName, '/');
    
            % Extract the second part of the split string, which contains 'QAM256_DP16'
            qamName = splitName{2};      
            
            %assign the n as DpOrder in the base workspace
            assignin('base','DpOrder', n);
    
            % Load the Simulink model without opening it
    
            if isChecked == 1

                app.MIMOOrderDropDown.Enable = "off";
                app.BandwidthMHzDropDown.Enable = "off";
                app.WiFiMCSDropDown.Enable = "off";
                app.ChannelPolarizationEditField.Enable = "off";
                app.RxXPolIsolationdBcEditField.Enable = "off";
                app.CheckBox.Enable = "off";
                app.VisualizedDPWiFiStreamDropDown.Enable = "off";
                app.RUNButton.Enable = "off";
                app.EditField.Value = "Initializing";
                app.RxDepolarizationIndexEditField.Value = 0.5016;

                out = sim(testModelName);
    
                rxDpIndex1 = out.rxDpIndex1;
    
                assignin('base', "rxDpIndex", rxDpIndex1);
    
            end
            
            %Allow time for the initial configuration to complete

            pause(3);

            assignin('base', "stated",'started');
            
            if ~isdeployed && isempty(coder.target)

                load_system(modelName);

            end
    
            % Open necessary scopes and trigger updates

            scopesToOpen = {"MCS13WiFi_DP32/[S]", ... 
                            "MCS13WiFi_DP32/[S*]", ...
                            "MCS13WiFi_DP32/[S']", ...
                            "MCS13WiFi_DP32/[SS]", ...
                            "MCS13WiFi_DP32/[S*S]", ...
                            "MCS13WiFi_DP32/[S'S]", ...
                            "MCS13WiFi_DP32/[SC]", ...
                            "MCS13WiFi_DP32/[S*C]", ...
                            "MCS13WiFi_DP32/[S'C]"};

            % Reopen the scopes

            for i = 1:length(scopesToOpen)

                if(app.RunCount == 1)
                    
                    if ~isdeployed && isempty(coder.target)

                        open_system(scopesToOpen{i});

                    end

                    pause(1);       

                else
                    
                    if ~isdeployed && isempty(coder.target)

                        close_system(scopesToOpen{i});

                    end

                    pause(1);

                end

            end
    
            if strcmp(qamName, 'MCS13WiFi_DP32')
    
                DisplayBER(app,modelName);
    
            end

        end
        
        function  Run_Simulink(app, n)
    
            isChecked = app.CheckBox.Value;
    
            %define function about the WiFi ADP MCS13
            
            if strcmp(app.WiFiMCSDropDown.Value, '13')
    
                %N = 12;
    
                if n == 32
    
                    % Replace with your Simulink model name
                    testModelName = 'Simulink/TS';
    
                    modelName = 'Simulink/MCS13WiFi_DP32';

                    RunModel(app, modelName, testModelName, isChecked, n);

                end

            end

        end
        
        function Get_InputValue(app)

            %Input Data n,N,xpi,dePol
            n = str2double(app.MIMOOrderDropDown.Value);

            m = str2double(app.VisualizedDPWiFiStreamDropDown.Value);
    
            xpi = app.RxXPolIsolationdBcEditField.Value;

            dePol = app.ChannelPolarizationEditField.Value;

            if dePol < 0.18
               
                dePol = 0.18;
            
            end
    
            %bandWidth = app.BandwidthMHzDropDown.Value;
    
            % calculate the channelDpIndex, rxDpIndex and set the workspace variable
            rxDpIndex = (1+ 10^(xpi/10))/2;

            channelDpIndex = (cos(dePol*pi/180) + sin(dePol*pi/180))/2;

            c1 = cos(dePol*pi/180);
            c3 = sin(dePol*pi/180);
    
            if channelDpIndex <= rxDpIndex
    
                channelDpIndex = rxDpIndex;
                
            end
    
            assignin('base', "channelDpIndex", channelDpIndex);
            assignin('base', 'rxDpIndex', rxDpIndex);
            assignin('base', 'streamIndex', m);
            assignin('base', 'c1', c1);
            assignin('base', 'c3', c3);
            
            app.ChPolarizationIndexEditField.Value = channelDpIndex;
            app.RxDepolarizationIndexEditField.Value = rxDpIndex;
           

            app.DPWiFiDataRateGbpsEditField.Value = n*2.882;
    
            app.EEditField.Value = 0;
            app.BEditField.Value = 0;
            app.BEREditField.Value = 0;
    
    
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Minimize MATLAB Desktop
            desktop = com.mathworks.mde.desk.MLDesktop.getInstance;
            desktop.getMainFrame.setMinimized(true);

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)

            app.MIMOOrderDropDown.Enable = "off";
            app.BandwidthMHzDropDown.Enable = "off";
            app.WiFiMCSDropDown.Enable = "off";
            app.ChannelPolarizationEditField.Enable = "off";
            app.RxXPolIsolationdBcEditField.Enable = "off";
            app.CheckBox.Enable = "off";
            app.VisualizedDPWiFiStreamDropDown.Enable = "off";
            app.RUNButton.Enable = "off";
            
            if(app.CheckBox.Value == 1)
                app.RxDepolarizationIndexEditField.Value = 0.5016;
            end
            
            app.EEditField.Value = 0;
            app.BEditField.Value = 0;
            app.BEREditField.Value = 0;
            app.EditField.Value = "Initializing";
            app.RunCount.Value = app.RunCount.Value + 1;                           
            Get_InputValue(app);
            
            n = str2double(app.MIMOOrderDropDown.Value);

            Run_Simulink(app, n);

        end

        % Value changed function: MIMOOrderDropDown
        function MIMOOrderDropDownValueChanged(app, event)
            
            Get_InputValue(app);
            
            
        end

        % Callback function: UIFigure, WiFiMCSDropDown
        function WiFiMCSDropDownValueChanged(app, event)

            Get_InputValue(app);
            app.UIFigure
        end

        % Value changed function: ChannelPolarizationEditField
        function ChannelPolarizationEditFieldValueChanged(app, event)

            Get_InputValue(app);
            
        end

        % Value changed function: RxXPolIsolationdBcEditField
        function RxXPolIsolationdBcEditFieldValueChanged(app, event)

            Get_InputValue(app);
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
             
            %clear;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @WiFiMCSDropDownValueChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RUNButton = uibutton(app.GridLayout, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.CheckBox = uicheckbox(app.GridLayout);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.RxAdaptationLabel = uilabel(app.GridLayout);
            app.RxAdaptationLabel.Text = 'Rx Adaptation ';
            app.DPWiFiTRXv4ModelLabel = uilabel(app.GridLayout);
            app.DPWiFiTRXv4ModelLabel.Text = '  DPWiFiTRXv4  Model';
            app.BEREditFieldLabel = uilabel(app.GridLayout);
            app.BEREditFieldLabel.Text = 'BER';
            app.BEREditField = uieditfield(app.GridLayout, 'numeric');
            app.BEREditField.Limits = [0 4];
            app.BEREditField.ValueDisplayFormat = '%.6e';
            app.BEREditField.Editable = 'off';
            app.RxDPIndexLabel = uilabel(app.GridLayout);
            app.RxDPIndexLabel.Text = 'Rx Depolarization Index';
            app.RxDepolarizationIndexEditField = uieditfield(app.GridLayout, 'numeric');
            app.RxDepolarizationIndexEditField.Limits = [0 1];
            app.RxDepolarizationIndexEditField.ValueDisplayFormat = '%.4f';
            app.RxDepolarizationIndexEditField.Editable = 'off';
            app.RxDepolarizationIndexEditField.Value = 0.5;
            app.ChPolarizationIndexEditFieldLabel = uilabel(app.GridLayout);
            app.ChPolarizationIndexEditFieldLabel.Text = 'Ch Polarization Index';
            app.ChPolarizationIndexEditField = uieditfield(app.GridLayout, 'numeric');
            app.ChPolarizationIndexEditField.Limits = [0 1];
            app.ChPolarizationIndexEditField.ValueDisplayFormat = '%.4f';
            app.ChPolarizationIndexEditField.Editable = 'off';
            app.ChPolarizationIndexEditField.Value = 0.5;
            app.RxXPolIsolationdBcEditFieldLabel = uilabel(app.GridLayout);
            app.RxXPolIsolationdBcEditFieldLabel.Text = 'Rx  X-Pol Isolation (dBc) ';
            app.RxXPolIsolationdBcEditField = uieditfield(app.GridLayout, 'numeric');
            app.RxXPolIsolationdBcEditField.Limits = [-30 -20];
            app.RxXPolIsolationdBcEditField.ValueDisplayFormat = '%.1f';
            app.RxXPolIsolationdBcEditField.ValueChangedFcn = createCallbackFcn(app, @RxXPolIsolationdBcEditFieldValueChanged, true);
            app.RxXPolIsolationdBcEditField.Value = -25;
            app.ChannelDepolarizationLabel = uilabel(app.GridLayout);
            app.ChannelDepolarizationLabel.Text = '  Channel Polarization (°) ';
            app.ChannelPolarizationEditField = uieditfield(app.GridLayout, 'numeric');
            app.ChannelPolarizationEditField.Limits = [0 40];
            app.ChannelPolarizationEditField.ValueDisplayFormat = '%.3f';
            app.ChannelPolarizationEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelPolarizationEditFieldValueChanged, true);
            app.ModulationCodingLabel = uilabel(app.GridLayout);
            app.ModulationCodingLabel.Text = 'WiFi MCS ';
            app.WiFiMCSDropDown = uidropdown(app.GridLayout);
            app.WiFiMCSDropDown.Items = {'13'};
            app.WiFiMCSDropDown.ValueChangedFcn = createCallbackFcn(app, @WiFiMCSDropDownValueChanged, true);
            app.WiFiMCSDropDown.Value = '13';
            app.DPMIMOOrderLabel = uilabel(app.GridLayout);
            app.DPMIMOOrderLabel.Text = ' MIMO Order ';
            app.MIMOOrderDropDown = uidropdown(app.GridLayout);
            app.MIMOOrderDropDown.Items = {'32'};
            app.MIMOOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @MIMOOrderDropDownValueChanged, true);
            app.MIMOOrderDropDown.Value = '32';
            app.EEditFieldLabel = uilabel(app.GridLayout);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.GridLayout, 'numeric');
            app.EEditField.Limits = [0 5000000];
            app.EEditField.ValueDisplayFormat = '%.0f';
            app.EEditField.Editable = 'off';
            app.outofLabel = uilabel(app.GridLayout);
            app.outofLabel.Text = 'B';
            app.BEditField = uieditfield(app.GridLayout, 'numeric');
            app.BEditField.Limits = [0 150000000];
            app.BEditField.ValueDisplayFormat = '%.0f';
            app.BEditField.Editable = 'off';
            app.BandwidthMHzLabel = uilabel(app.GridLayout);
            app.BandwidthMHzLabel.Text = 'Bandwidth (MHz) ';
            app.BandwidthMHzDropDown = uidropdown(app.GridLayout);
            app.BandwidthMHzDropDown.Items = {'320'};
            app.BandwidthMHzDropDown.Value = '320';
            app.DPWiFiDataRateGbpsLabel = uilabel(app.GridLayout);
            app.DPWiFiDataRateGbpsLabel.Text = 'DPWiFi Data Rate (Gbps) ';
            app.DPWiFiDataRateGbpsEditField = uieditfield(app.GridLayout, 'numeric');
            app.DPWiFiDataRateGbpsEditField.Editable = 'off';
            app.MonitoredDPWiFiStreamLabel = uilabel(app.GridLayout);
            app.MonitoredDPWiFiStreamLabel.Text = 'Monitored DPWiFi Stream ';
            app.VisualizedDPWiFiStreamDropDown = uidropdown(app.GridLayout);
            app.VisualizedDPWiFiStreamDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32'};
            app.VisualizedDPWiFiStreamDropDown.Value = '32';
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'READY';
            app.Label = uilabel(app.GridLayout);
            app.Label.Enable = 'off';
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.RunCount = uieditfield(app.GridLayout, 'numeric');
            app.RunCount.Editable = 'off';
            app.RunCount.Enable = 'off';
            app.RunCount.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DPWiFi_TRX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end